classdef App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StopFrequency2Slider            matlab.ui.control.Slider
        Freq4Label                      matlab.ui.control.Label
        PassFrequency2Slider            matlab.ui.control.Slider
        PassFrequency2SliderLabel       matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        GenerationNumberEditField       matlab.ui.control.NumericEditField
        GenerationNumberEditFieldLabel  matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        GenerateFilterButton            matlab.ui.control.Button
        StopFrequency1Slider            matlab.ui.control.Slider
        StopFrequency1SliderLabel       matlab.ui.control.Label
        PassFrequency1Slider            matlab.ui.control.Slider
        PassFrequency1SliderLabel       matlab.ui.control.Label
        TypeOfFilterDropDown            matlab.ui.control.DropDown
        TypeOfFilterDropDownLabel       matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filterCoefficientsMatrix = [] % Description
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
        
        function cost = filter_fitness_LowPassFilter(app,coeffs, pass_freq, stop_freq, order)
            % Create the filter with the current coefficients
            h = coeffs;  % FIR filter coefficients
        
            % Calculate the frequency response
            [H, w] = freqz(h, 1, 1024, 'whole');  % Use 'whole' for full frequency range
            H_dB = 20 * log10(abs(H));  % Convert to dB scale
        
            % Create the ideal target response (in dB)
            target_dB = zeros(size(w));  % Initialize with 0 dB (ideal passband)
        
            % Define the indices for passband and stopband
            passband_indices = (w <= pass_freq);  % Indices where frequency is <= passband
            stopband_indices = (w >= stop_freq);  % Indices where frequency is >= stopband
        
            % Set target for passband (0 dB)
            target_dB(passband_indices) = 0;
        
            % Set target for stopband (-60 dB)
            target_dB(stopband_indices) = -60;
        
            % Define the transition region (between passband and stopband)
            transition_indices = (w > pass_freq) & (w < stop_freq);
            
            % Apply a linear transition between 0 dB and -60 dB in the transition region
            transition_slope = -60 / (stop_freq - pass_freq);
            target_dB(transition_indices) = transition_slope * (w(transition_indices) - pass_freq);
        
            % Compute the error between the filter's response and the target response
            cost = sum((H_dB - target_dB).^2);  % Sum of squared differences
        end
        function cost = filter_fitness_HighPassFilter(app, coeffs, stop_freq, pass_freq, order)
    % Create the filter with the current coefficients
    h = coeffs;  % FIR filter coefficients

    % Calculate the frequency response
    [H, w] = freqz(h, 1, 1024, 'whole');  % Use 'whole' for full frequency range
    H_dB = 20 * log10(abs(H));  % Convert to dB scale

    % Create the ideal target response (in dB)
    target_dB = zeros(size(w));  % Initialize with 0 dB (ideal passband)

    % Define the indices for passband and stopband for HPF
    passband_indices = (w >= pass_freq);  % Indices where frequency is >= passband
    stopband_indices = (w <= stop_freq);  % Indices where frequency is <= stopband

    % Set target for passband (0 dB)
    target_dB(passband_indices) = 0;

    % Set target for stopband (-60 dB)
    target_dB(stopband_indices) = -60;

    % Define the transition region (between stopband and passband)
    transition_indices = (w > stop_freq) & (w < pass_freq);

    % Apply a linear transition between -60 dB and 0 dB in the transition region
    transition_slope = 60 / (pass_freq - stop_freq);
    target_dB(transition_indices) = -60 + transition_slope * (w(transition_indices) - stop_freq);

    % Compute the error between the filter's response and the target response
    cost = sum((H_dB - target_dB).^2);  % Sum of squared differences
        end

    function cost = filter_fitness_BandPassFilter(app, coeffs, pass_freq1, pass_freq2, stop_freq1, stop_freq2, order)
    % Create the filter with the current coefficients
    h = coeffs;  % FIR filter coefficients

    % Calculate the frequency response
    [H, w] = freqz(h, 1, 1024, 'whole');  % Use 'whole' for full frequency range
    H_dB = 20 * log10(abs(H));  % Convert to dB scale

    % Create the ideal target response (in dB)
    target_dB = zeros(size(w));  % Initialize with 0 dB (ideal passband)

    % Define the indices for passbands and stopbands
    passband_indices = (w >= pass_freq1) & (w <= pass_freq2);
    stopband_indices1 = (w <= stop_freq1);
    stopband_indices2 = (w >= stop_freq2);

    % Set target for passband (0 dB)
    target_dB(passband_indices) = 0;

    % Set target for stopbands (-60 dB)
    target_dB(stopband_indices1) = -60;
    target_dB(stopband_indices2) = -60;

    % Define the transition regions
    transition_indices1 = (w > stop_freq1) & (w < pass_freq1);
    transition_indices2 = (w > pass_freq2) & (w < stop_freq2);

    % Apply a linear transition for the lower transition region
    transition_slope1 = 60 / (pass_freq1 - stop_freq1);
    target_dB(transition_indices1) = -60 + transition_slope1 * (w(transition_indices1) - stop_freq1);

    % Apply a linear transition for the upper transition region
    transition_slope2 = -60 / (stop_freq2 - pass_freq2);
    target_dB(transition_indices2) = transition_slope2 * (w(transition_indices2) - pass_freq2);
    % Compute the error between the filter's response and the target response
    cost = sum((H_dB - target_dB).^2);  % Sum of squared differences
    end

    function cost = filter_fitness_BandStopFilter(app, coeffs, pass_freq1, pass_freq2, stop_freq1, stop_freq2, order)
    % Create the filter with the current coefficients
    h = coeffs;  % FIR filter coefficients

    % Calculate the frequency response
    [H, w] = freqz(h, 1, 1024, 'whole');  % Use 'whole' for full frequency range
    H_dB = 20 * log10(abs(H));  % Convert to dB scale

    % Create the ideal target response (in dB)
    target_dB = zeros(size(w));  % Initialize with 0 dB (ideal passband)

    % Define the indices for passbands and stopband
    passband_indices1 = (w <= pass_freq1);
    passband_indices2 = (w >= pass_freq2);
    stopband_indices = (w >= stop_freq1) & (w <= stop_freq2);

    % Set target for passbands (0 dB)
    target_dB(passband_indices1) = 0;
    target_dB(passband_indices2) = 0;

    % Set target for stopband (-60 dB)
    target_dB(stopband_indices) = -60;

    % Define the transition regions
    transition_indices1 = (w > pass_freq1) & (w < stop_freq1);
    transition_indices2 = (w > stop_freq2) & (w < pass_freq2);

    % Apply a linear transition for the lower transition region
    transition_slope1 = -60 / (stop_freq1 - pass_freq1);
    target_dB(transition_indices1) = transition_slope1 * (w(transition_indices1) - pass_freq1);

    % Apply a linear transition for the upper transition region
    transition_slope2 = 60 / (pass_freq2 - stop_freq2);
    target_dB(transition_indices2) = -60 + transition_slope2 * (w(transition_indices2) - stop_freq2);

    % Compute the error between the filter's response and the target response
    cost = sum((H_dB - target_dB).^2);  % Sum of squared differences
    end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateFilterButton
        function GenerateFilterButtonPushed(app, event)
% function GenerateFilterButtonPushed(app, event)
    app.filterCoefficientsMatrix = [];
    order = app.FilterOrderEditField.Value;
    
        pass_freq = app.PassFrequency1Slider.Value;
        stop_freq = app.StopFrequency1Slider.Value;
        pass2_freq = app.PassFrequency2Slider.Value;
        stop2_freq = app.StopFrequency2Slider.Value;
        % GA parameters


        num_coeffs = order + 1;
        population_size = 50;
        num_generations = 500;
        crossover_rate = 0.8;
        mutation_rate = 0.1;
        lb = -1 * ones(1, num_coeffs);
        ub = 1 * ones(1, num_coeffs);
    
        % Initialize population with random coefficients
        population = lb + (ub - lb) .* rand(population_size, num_coeffs);
        fitness_values = zeros(population_size, 1);
    
        % Initialize an empty matrix to store the filter coefficients
        % app.filterCoefficientsMatrix = [];
    % 
        % Main GA loop
        for gen = 1:num_generations
            % Evaluate fitness for each individual
            app.GenerationNumberEditField.Value = gen;
            if app.TypeOfFilterDropDown.Value == "LowPassFilter"
                for i = 1:population_size
                    fitness_values(i) = app.filter_fitness_LowPassFilter(population(i, :), pass_freq, stop_freq, order);
                end
            end
            if app.TypeOfFilterDropDown.Value == "HighPassFilter"
                for i = 1:population_size
                    fitness_values(i) = app.filter_fitness_HighPassFilter(population(i, :), pass_freq, stop_freq, order);
                end
            end
            if app.TypeOfFilterDropDown.Value == "BandPassFilter"
                for i = 1:population_size
                    fitness_values(i) = app.filter_fitness_BandPassFilter(population(i, :), pass_freq, pass2_freq, stop_freq, stop2_freq, order);
                end
            end
            if app.TypeOfFilterDropDown.Value == "BandStopFilter"
                for i = 1:population_size
                    fitness_values(i) = app.filter_fitness_BandStopFilter(population(i, :), pass_freq, stop2_freq, stop_freq, pass2_freq, order);
                end
            end

            % Select the best individual as the current best solution
            [~, best_idx] = min(fitness_values);
            best_coeffs = population(best_idx, :);
    
            % Elitism: Keep the best individual
            new_population = population(best_idx, :);
    
            % Selection (Tournament Selection)
            for i = 2:population_size
                idx1 = randi(population_size);
                idx2 = randi(population_size);
                if fitness_values(idx1) < fitness_values(idx2)
                    parent = population(idx1, :);
                else
                    parent = population(idx2, :);
                end
    
                % Crossover
                if rand < crossover_rate
                    partner_idx = randi(population_size);
                    partner = population(partner_idx, :);
                    crossover_point = randi(num_coeffs);
                    child = [parent(1:crossover_point), partner(crossover_point+1:end)];
                else
                    child = parent;
                end
    
                % Mutation
                for j = 1:num_coeffs
                    if rand < mutation_rate
                        child(j) = lb(j) + (ub(j) - lb(j)) * rand;
                    end
                end
                % Add child to the new population
                new_population = [new_population; child];
            end
    
            % Update the population
            population = new_population;
    
            % Append the best filter coefficients of the generation to the matrix
            app.filterCoefficientsMatrix = [app.filterCoefficientsMatrix; best_coeffs];
    
            % Check for convergence (simple stagnation check)
            if gen > 10 && std(fitness_values) < 1e-3
                break;
            end
        end
        app.GenerationNumberEditField.Value = gen;
 
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
gen_idx = app.GenerationNumberEditField.Value;
filter_coeffs = app.filterCoefficientsMatrix(gen_idx, :);
cla(app.UIAxes2);
[h, f] = freqz(filter_coeffs, 1, 1024, 'half');  % Frequency response, 1024 points
plot(app.UIAxes, f, abs(h));  % Plot magnitude response
b = filter_coeffs;  % Numerator (filter coefficients)
a = 1;  % Denominator for FIR filter is always 1
zeros_b = roots(b);  % Zeros are the roots of the numerator (b)


N = length(b) - 1;  % Number of poles is the order of the filter
poles_a = zeros(1, N);  % Poles at the origin
hold(app.UIAxes2, 'on');  % Hold the plot to overlay zeros and poles

% Plot Zeros (denoted by 'o') and Poles (denoted by 'x') at the origin
plot(app.UIAxes2, real(zeros_b), imag(zeros_b), 'o', 'MarkerSize', 10, 'LineWidth', 2, 'MarkerEdgeColor', 'r');  
plot(app.UIAxes2, real(poles_a), imag(poles_a), 'x', 'MarkerSize', 10, 'LineWidth', 2, 'MarkerEdgeColor', 'r');  
theta = linspace(0, 2*pi, 100);  % Create angle values
unit_circle_x = cos(theta);  % X coordinates for unit circle
unit_circle_y = sin(theta);  % Y coordinates for unit circle
plot(app.UIAxes2, unit_circle_x, unit_circle_y, 'k--', 'LineWidth', 1.5);  % Plot unit circle as a dashed line

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequency Response (Mag)')
            xlabel(app.UIAxes, 'Omega')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pole Zero ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TypeOfFilterDropDownLabel = uilabel(app.UIFigure);
            app.TypeOfFilterDropDownLabel.Text = 'Type Of Filter';
            app.TypeOfFilterDropDown = uidropdown(app.UIFigure);
            app.TypeOfFilterDropDown.Items = {'LowPassFilter', 'HighPassFilter', 'BandPassFilter', 'BandStopFilter'};
            app.TypeOfFilterDropDown.Value = 'LowPassFilter';
            app.PassFrequency1SliderLabel = uilabel(app.UIFigure);
            app.PassFrequency1SliderLabel.Text = 'Pass Frequency 1';
            app.PassFrequency1Slider = uislider(app.UIFigure);
            app.PassFrequency1Slider.Limits = [0 3.141];
            app.PassFrequency1Slider.MajorTicks = [0 0.79 1.58 2.37 3.141];
            app.PassFrequency1Slider.MajorTickLabels = {'0', '0.79', '1.58', '2.37', '3.141'};
            app.StopFrequency1SliderLabel = uilabel(app.UIFigure);
            app.StopFrequency1SliderLabel.Text = 'Stop Frequency 1';
            app.StopFrequency1Slider = uislider(app.UIFigure);
            app.StopFrequency1Slider.Limits = [0 3.141];
            app.StopFrequency1Slider.MajorTicks = [0 0.79 1.58 2.37 3.141];
            app.StopFrequency1Slider.MajorTickLabels = {'0', '0.79', '1.58', '2.37', '3.141'};
            app.GenerateFilterButton = uibutton(app.UIFigure, 'push');
            app.GenerateFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFilterButtonPushed, true);
            app.GenerateFilterButton.Text = 'Generate Filter';
            app.FilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerationNumberEditFieldLabel = uilabel(app.UIFigure);
            app.GenerationNumberEditFieldLabel.Text = 'Generation Number';
            app.GenerationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerationNumberEditField.Editable = 'off';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PassFrequency2SliderLabel = uilabel(app.UIFigure);
            app.PassFrequency2SliderLabel.Text = 'Pass Frequency 2';
            app.PassFrequency2Slider = uislider(app.UIFigure);
            app.PassFrequency2Slider.Limits = [0 3.141];
            app.PassFrequency2Slider.MajorTicks = [0 0.79 1.58 2.37 3.141];
            app.PassFrequency2Slider.MajorTickLabels = {'0', '0.79', '1.58', '2.37', '3.141'};
            app.Freq4Label = uilabel(app.UIFigure);
            app.Freq4Label.Text = 'Stop Frequency 2';
            app.StopFrequency2Slider = uislider(app.UIFigure);
            app.StopFrequency2Slider.Limits = [0 3.14159265358979];
            app.StopFrequency2Slider.MajorTicks = [0 0.79 1.58 2.37 3.14159265358979];
            app.StopFrequency2Slider.MajorTickLabels = {'0', '0.79', '1.58', '2.37', '3.141'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end